classdef RoastGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        TabGroup2                       matlab.ui.container.TabGroup
        MainmenuTab                     matlab.ui.container.Tab
        LoadsettingsButton              matlab.ui.control.Button
        SavesettingsButton              matlab.ui.control.Button
        OptionalSelectT2MRIfileLabel    matlab.ui.control.Label
        SelectT1MRIfileLabel            matlab.ui.control.Label
        T2FileLoc                       matlab.ui.control.EditField
        BrowseButton_2                  matlab.ui.control.Button
        T1FileLoc                       matlab.ui.control.EditField
        BrowseButton                    matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        ElectrodeTabGroup               matlab.ui.container.TabGroup
        ElecTab                         matlab.ui.container.Tab
        ElectrodeconfigurationLabel     matlab.ui.control.Label
        NumberofelectrodesEditField     matlab.ui.control.NumericEditField
        NumberofelectrodesEditFieldLabel  matlab.ui.control.Label
        CaptypeDropDown                 matlab.ui.control.DropDown
        CaptypeDropDownLabel            matlab.ui.control.Label
        TargetedtESTab                  matlab.ui.container.Tab
        ElectricfieldorientationDropDown  matlab.ui.control.DropDown
        ElectricfieldorientationDropDownLabel  matlab.ui.control.Label
        AlgorithmDropDown               matlab.ui.control.DropDown
        AlgorithmDropDownLabel          matlab.ui.control.Label
        PriorityDropDown                matlab.ui.control.DropDown
        PriorityDropDownLabel           matlab.ui.control.Label
        CoordinatetypeDropDown          matlab.ui.control.DropDown
        CoordinatetypeDropDownLabel     matlab.ui.control.Label
        CapType1010TypeDiscRadius6mmHeight2mmLabel  matlab.ui.control.Label
        LoadsettingsButton_2            matlab.ui.control.Button
        SavesettingsButton_2            matlab.ui.control.Button
        OptionalSelectT2MRIfileLabel_2  matlab.ui.control.Label
        SelectT1MRIfileLabel_2          matlab.ui.control.Label
        T2FileLoc_2                     matlab.ui.control.EditField
        BrowseButton_4                  matlab.ui.control.Button
        T1FileLoc_2                     matlab.ui.control.EditField
        BrowseButton_3                  matlab.ui.control.Button
        StopButton_2                    matlab.ui.control.Button
        StartButton_2                   matlab.ui.control.Button
        AdvancedTab                     matlab.ui.container.Tab
        TabGroup4                       matlab.ui.container.TabGroup
        ConductivityvaluesTab           matlab.ui.container.Tab
        SmLabel_8                       matlab.ui.control.Label
        SmLabel_7                       matlab.ui.control.Label
        SmLabel_6                       matlab.ui.control.Label
        SmLabel_5                       matlab.ui.control.Label
        SmLabel_4                       matlab.ui.control.Label
        SmLabel_3                       matlab.ui.control.Label
        SmLabel_2                       matlab.ui.control.Label
        SmLabel                         matlab.ui.control.Label
        ElectrodeEditField              matlab.ui.control.NumericEditField
        ElectrodeEditFieldLabel         matlab.ui.control.Label
        GelEditField                    matlab.ui.control.NumericEditField
        GelEditFieldLabel               matlab.ui.control.Label
        AirEditField                    matlab.ui.control.NumericEditField
        AirEditFieldLabel               matlab.ui.control.Label
        SkinEditField                   matlab.ui.control.NumericEditField
        SkinEditFieldLabel              matlab.ui.control.Label
        BoneEditField                   matlab.ui.control.NumericEditField
        BoneEditFieldLabel              matlab.ui.control.Label
        CerebrospinalfluidEditField     matlab.ui.control.NumericEditField
        CerebrospinalfluidLabel         matlab.ui.control.Label
        GraymatterEditField             matlab.ui.control.NumericEditField
        GraymatterEditFieldLabel        matlab.ui.control.Label
        WhitematterEditField            matlab.ui.control.NumericEditField
        WhitematterEditFieldLabel       matlab.ui.control.Label
        MeshoptionsTab                  matlab.ui.container.Tab
        MaximaltetrahedralelementvolumeEditField  matlab.ui.control.NumericEditField
        MaximaltetrahedralelementvolumeEditFieldLabel  matlab.ui.control.Label
        MaximalradiusedgeratioEditField  matlab.ui.control.NumericEditField
        MaximalradiusedgeratioEditFieldLabel  matlab.ui.control.Label
        MaximumdistancebetweenEditField  matlab.ui.control.NumericEditField
        MaximumdistancebetweenEditFieldLabel  matlab.ui.control.Label
        MinimalangleofasurfacetriangleEditField  matlab.ui.control.NumericEditField
        MinimalangleofasurfacetriangleEditFieldLabel  matlab.ui.control.Label
        MaximalsurfaceelementsizeEditField  matlab.ui.control.NumericEditField
        MaximalsurfaceelementsizeEditFieldLabel  matlab.ui.control.Label
        ZeropaddingEditField            matlab.ui.control.NumericEditField
        ZeropaddingEditFieldLabel       matlab.ui.control.Label
        ResamplingCheckBox              matlab.ui.control.CheckBox
        SimulationtagEditField          matlab.ui.control.EditField
        SimulationtagEditFieldLabel     matlab.ui.control.Label
        InfoTab                         matlab.ui.container.Tab
        Label                           matlab.ui.control.Label
        HelpfullinksLabel_2             matlab.ui.control.Label
        Hyperlink3_2                    matlab.ui.control.Hyperlink
        Hyperlink3                      matlab.ui.control.Hyperlink
        Hyperlink2                      matlab.ui.control.Hyperlink
        Hyperlink                       matlab.ui.control.Hyperlink
        RightPanel                      matlab.ui.container.Panel
        TabGroup3                       matlab.ui.container.TabGroup
        ElectrodepositionTab            matlab.ui.container.Tab
        ClicktoselectLabel              matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        DynamicElecTab % Electrode settings tab
        ElecPosLabel % Electrode position
        ElecPosValue
        ElecMag
        ElecMagValue
        ElecTypeLabel
        ElecSizeLabel
        ElecSizeValue

        RadiusValue
        RadiusLabel
        HeightValue
        HeightLabel
        ElecTypeDropDown

        ElecPosSelect

        InnerRadiusLabel
        InnerRadiusValue
        OuterRadiusLabel
        OuterRadiusValue
        
        Image
        ElecImg
        DesiredElecImgPos
        ElecImgTxt

        DyanmicStimIntensity % Stimulation intensity
        DynamicElecType % Electrode type
        OrientationDropDown
        DynamicOrientationDropDown
        DynamicOrientationText

        PreviousElecType

        LengthLabel
        LengthValue

        WidthLabel
        WidthValue
        OrientationText

        LEFT_COLLUMN_WIDTH = 378;
        IMG_RATIO = 683 / 638;
    end


    methods (Access = private)
        function CreateElecTab(app, TabNum)
            % Dynamicaly create new electrode settings tabs as the user
            % requests them
            TabNum = TabNum -1;
            % Create tab
            app.DynamicElecTab{TabNum} = uitab(app.ElectrodeTabGroup);
            app.DynamicElecTab{TabNum}.Title = string(TabNum+1);
        end
        
        function FillElecTab(app, TabNum)
            % Create electrode position text
            if TabNum == 1
                currentTabObj = app.ElecTab;
            else
                currentTabObj = app.DynamicElecTab{TabNum-1};
            end
            app.ElecPosLabel{TabNum} = uilabel(currentTabObj);
            app.ElecPosLabel{TabNum}.Position = [190 115 43 22];
            app.ElecPosLabel{TabNum}.Text = 'Position';

            % Create PositionEditField
            app.ElecPosValue{TabNum} = uieditfield(currentTabObj, 'text');
            app.ElecPosValue{TabNum}.ValueChangedFcn = createCallbackFcn(app, @PositionEditFieldValueChanged, true);
            app.ElecPosValue{TabNum}.Position = [260 115 34 22];
            app.ElecPosValue{TabNum}.HorizontalAlignment = 'right';

            % Create stimulation magnitude text
            app.ElecMag{TabNum} = uilabel(currentTabObj);
            app.ElecMag{TabNum}.Position =  [6 150 142 22];
            app.ElecMag{TabNum}.Text = 'Stimulation intensity (mA)';

            % Create stimulation magnitude editable field
            app.ElecMagValue{TabNum} = uieditfield(currentTabObj, 'numeric');
            app.ElecMagValue{TabNum}.HorizontalAlignment = 'right';
            app.ElecMagValue{TabNum}.Position = [168 150 37 22];

            % Create TypeDropDownLabel
            app.ElecTypeLabel{TabNum} = uilabel(currentTabObj);
            app.ElecTypeLabel{TabNum}.Position = [6 115 30 22];
            app.ElecTypeLabel{TabNum}.Text = 'Type';

            % Create TypeDropDown
            app.ElecTypeDropDown{TabNum} = uidropdown(currentTabObj);
            app.ElecTypeDropDown{TabNum}.Items = {'Disc (default)', 'Pad', 'Ring'};
            app.ElecTypeDropDown{TabNum}.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.ElecTypeDropDown{TabNum}.Position = [50 115 103 22];
            app.ElecTypeDropDown{TabNum}.Value = 'Disc (default)';
            
            app.PreviousElecType{TabNum} = 'Disc (default)';

            % Create RadiusLabel{TabNum}
            app.RadiusLabel{TabNum} = uilabel(currentTabObj);
            app.RadiusLabel{TabNum}.Position = [6 80 70 22];
            app.RadiusLabel{TabNum}.Text = 'Radius (mm)';

            % Create RadiusValue{TabNum}
            app.RadiusValue{TabNum} = uieditfield(currentTabObj, 'numeric');
            app.RadiusValue{TabNum}.Position = [117 80 34 22];

            % Create HeightmmEditFieldLabel
            app.HeightLabel{TabNum} = uilabel(currentTabObj);
            app.HeightLabel{TabNum}.Position = [190 80 71 22];
            app.HeightLabel{TabNum}.Text  = 'Height (mm)';

            % Create HeightmmEditField
            app.HeightValue{TabNum} = uieditfield(currentTabObj, 'numeric');
            app.HeightValue{TabNum}.Position = [260 80 34 22];

        end


        function LFDeleteElecTab(app, TabNum)
            % Delete electrode configuration tab requested
            delete(app.LFDynamicElecTab{TabNum});
            app.LFDynamicElecTab(TabNum) = [];

            delete(app.LFElecTypeLabel{TabNum});
            app.LFElecTypeLabel(TabNum) = [];

            delete(app.LFRadiusLabel{TabNum});
            app.LFRadiusLabel(TabNum) = [];

            delete(app.LFHeightLabel{TabNum});
            app.LFHeightLabel(TabNum) = [];
        end


        function DeleteElecTab(app, TabNum)
            % Delete electrode configuration tab requested
            delete(app.DynamicElecTab{TabNum});
            app.DynamicElecTab(TabNum) = [];

            delete(app.ElecPosLabel{TabNum});
            app.ElecPosLabel(TabNum) = [];

            delete(app.ElecPosValue{TabNum});
            app.ElecPosValue(TabNum) = [];

            delete(app.ElecMag{TabNum});
            app.ElecMag(TabNum) = [];

            delete(app.ElecMagValue{TabNum});
            app.ElecMagValue(TabNum) = [];

            delete(app.ElecTypeLabel{TabNum});
            app.ElecTypeLabel(TabNum) = [];

            delete(app.ElecTypeDropDown{TabNum});
            app.ElecTypeDropDown(TabNum) = [];

            delete(app.RadiusValue{TabNum});
            app.RadiusValue(TabNum) = [];

            delete(app.RadiusLabel{TabNum});
            app.RadiusLabel(TabNum) = [];

            delete(app.HeightValue{TabNum});
            app.HeightValue(TabNum) = [];

            delete(app.HeightLabel{TabNum});
            app.HeightLabel(TabNum) = [];
        end

        function CustomUpdateAppLayout(app, event)

            app.Image.Visible = 'off';
            for img = 1:size(app.ElecImg, 2) % Make all images invisible
                app.ElecImg{img}.Visible = 'off';
                app.ElecImgTxt{img}.Visible = 'off';
            end
            % CustomUpdateAppLayout(app, event) called when the app is
            % resized. This function repositions / resizes elements.
            % Change right tab size to match window

            pause(0.1);

            app.ClicktoselectLabel.Position = [9 app.UIFigure.Position(4)-60 79 22];
            
            app.TabGroup3.Position(1) = 5;
            app.TabGroup3.Position(2) = 8;
            app.TabGroup3.Position(3) = app.UIFigure.Position(3) - app.LEFT_COLLUMN_WIDTH;
            app.TabGroup3.Position(4) = app.UIFigure.Position(4) - 13;
   
            % If electrode position tab selected, change image size to
            % match window    
            pause (0.1);
            tabRatio = app.TabGroup3.Position(3) / app.TabGroup3.Position(4);
            if strcmp(app.TabGroup3.SelectedTab.Title,'Electrode position')
                if tabRatio > app.IMG_RATIO %Spaces to the side
                    app.Image.Position(1) = (app.TabGroup3.Position(3) - app.Image.Position(3))/2;
                    app.Image.Position(2) = 5;
                    app.Image.Position(4) =  app.TabGroup3.Position(4) - 40;
                else
                    app.Image.Position(3) = app.TabGroup3.Position(3);
                    app.Image.Position(1) = 0;
                    app.Image.Position(2) = (app.TabGroup3.Position(4) - app.Image.Position(4))/2 + 5;
                end
            end

            % Update position of any electrode images that exists  
            if size(app.ElecImg, 2) > 0 % Image exists
                for img = 1:size(app.ElecImg, 2) % Move all images
                if tabRatio > app.IMG_RATIO % Spaces to the side
                    ElecSize = (app.TabGroup3.Position(4)*(1/app.IMG_RATIO))/10;
                else
                    ElecSize = (app.TabGroup3.Position(3)*app.IMG_RATIO)/10;
                end
                    app.ElecImg{img}.Position(3) = ElecSize;
                    app.ElecImg{img}.Position(4) = ElecSize;

                    positions = Percentage2Pixels(app, app.DesiredElecImgPos{img}.Position, ElecSize);
                    app.ElecImg{img}.Position(1) = positions(1);
                    app.ElecImg{img}.Position(2) = positions(2);
                    % Size and position of text = comparing against
                    % ElecSize which as some set ratios
                    % Update position and size of electrode number text
                    app.ElecImgTxt{img}.Position = [positions(1)+(ElecSize/2.5), positions(2)+(ElecSize/4.6), (ElecSize/1.87), (ElecSize/1.87)];
                    app.ElecImgTxt{img}.FontSize = round(ElecSize/2.24);
                end
            end
            app.Image.Visible = 'on';
            for img = 1:size(app.ElecImg, 2) % Make all images invisible
                app.ElecImg{img}.Visible = 'on';
                app.ElecImgTxt{img}.Visible = 'on';
            end
        end

        function [pixels] = Percentage2Pixels(app, percentages, ElecSize)
            % Given the percentage of the way across and image an
            % element is intended to be, return the number of pixels
            % that corresponds to taking into account the app window
            % size
            tabRatio = (app.TabGroup3.Position(3)) / (app.TabGroup3.Position(4));
            if tabRatio > app.IMG_RATIO % Spaces to the side of image
                % X position = percentage of the way across the image + empty space - half electrode size
                xPixels = app.Image.Position(3) * (percentages(1)/100) + app.Image.Position(1) - (ElecSize/2); 
                yPixels = percentages(2) * (app.Image.Position(4) / 100);
            else % Spaces above and below image
                % Y position = percentage of way across image + empty space
                % at side of image - top of app window
                yPixels = (percentages(2) * ((app.Image.Position(3) * app.IMG_RATIO)/ 100)) + app.Image.Position(2) - 40;
                xPixels = (percentages(1) * (app.Image.Position(3) / 100));
            end
            pixels = [xPixels, yPixels];
        end

        function HighlightElectrodePos(app, PosToUpdate)
            % Convert image position in % to pixel number
            tabRatio = app.TabGroup3.Position(3) / app.TabGroup3.Position(4);
            CurrentTab = str2double(app.ElectrodeTabGroup.SelectedTab.Title);
            % For central row of electrodes Y pos is about 15% of height
            % Calculate percentage -> electrode positions (inverse of
            % existing functions)
            if strcmp(app.CaptypeDropDown.Value, '1010 (default)')
                if tabRatio > app.IMG_RATIO % Spaces to the side
                    ElecSize = (app.TabGroup3.Position(4)*(1/app.IMG_RATIO))/10;
                else
                    ElecSize = (app.TabGroup3.Position(3)*app.IMG_RATIO)/10;
                end
                if strcmp(PosToUpdate, 'Fp1')
                    percentages = [40.9, 79.5];
                elseif strcmp(PosToUpdate, 'Fp2')
                    percentages = [59.1, 79.5];

                elseif strcmp(PosToUpdate, 'F7')
                    percentages = [27.2, 65.9];
                elseif strcmp(PosToUpdate, 'F8')
                    percentages = [72.8, 66.3];

                elseif strcmp(PosToUpdate, 'F3')
                    percentages = [38.4, 63.2];
                elseif strcmp(PosToUpdate, 'Fz')
                    percentages = [50 63.2];
                elseif strcmp(PosToUpdate, 'F4')
                    percentages = [61.6, 63.2];

                elseif strcmp(PosToUpdate, 'T3')
                    percentages = [21.5, 44.1];
                elseif strcmp(PosToUpdate, 'T4')
                    percentages = [78, 44.1];

                elseif strcmp(PosToUpdate, 'C3')
                    percentages = [35.7, 44.1];
                elseif strcmp(PosToUpdate, 'Cz')
                    percentages = [50, 44.1];
                elseif strcmp(PosToUpdate, 'C4')
                    percentages = [64.2, 44.1];

                elseif strcmp(PosToUpdate, 'P3')
                    percentages = [38.4 25.3];
                elseif strcmp(PosToUpdate, 'Pz')
                    percentages = [50 25.4];
                elseif strcmp(PosToUpdate, 'P4')
                    percentages = [61.6 25.1];

                elseif strcmp(PosToUpdate, 'T5')
                    percentages = [27.2, 22.3];
                elseif strcmp(PosToUpdate, 'T6')
                    percentages = [72.8, 22.3];

                elseif strcmp(PosToUpdate, 'O1')
                    percentages = [40.9, 8.7];
                elseif strcmp(PosToUpdate, 'O2')
                    percentages = [59.1, 8.7];
                end
            end

            % Save desired percentages for use if app is resized in the
            % future
            app.DesiredElecImgPos{CurrentTab}.Position = percentages;

            % Convert percentage of distance across image to pixels based
            % on current app window size
            pos = Percentage2Pixels(app, percentages, ElecSize);

            app.ElecImg{CurrentTab} = uiimage(app.ElectrodepositionTab);
            app.ElecImg{CurrentTab}.Position = [pos(1), pos(2), ElecSize, ElecSize];

            ElecType = app.ElecTypeDropDown{str2double(app.ElectrodeTabGroup.SelectedTab.Title)}.Value;
    

            if strcmp(ElecType, 'Disc (default)')
                imageFile = '\Images\Disc.png';
            elseif strcmp(ElecType, 'Pad')
                imageFile = '\Images\Pad.png';
            elseif strcmp(ElecType, 'Ring')
                imageFile = '\Images\Ring.png';
            end
            absPath = fileparts(mfilename('fullpath')); % absolute path to the folder containing the mlapp file
            imagePath = fullfile(absPath, imageFile); % absolute path to the image file
            app.ElecImg{CurrentTab}.ImageSource = imagePath;

            %Create text label for the electrode number
            app.ElecImgTxt{CurrentTab} = uilabel(app.ElectrodepositionTab);
            app.ElecImgTxt{CurrentTab}.FontColor = [1, 1, 1];
            app.ElecImgTxt{CurrentTab}.FontSize = 25;
            app.ElecImgTxt{CurrentTab}.FontWeight = 'bold';
            app.ElecImgTxt{CurrentTab}.Text = app.ElectrodeTabGroup.SelectedTab.Title;
            if str2double(app.ElectrodeTabGroup.SelectedTab.Title) > 9 % Double digits
                app.ElecImgTxt{CurrentTab}.Position = [pos(1)+11, pos(2)+10, 30, 30];
            else
                app.ElecImgTxt{CurrentTab}.Position = [pos(1)+17, pos(2)+10, 30, 30];
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Create Image
            app.Image = uiimage(app.ElectrodepositionTab);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.Position = [0 5 app.TabGroup3.Position(3) app.TabGroup3.Position(4)-40];
            app.Image.ScaleMethod = 'fit';
            imageFile = '\Images\1020EEG.png';
            absPath = fileparts(mfilename('fullpath')); % absolute path to the folder containing the mlapp file
            imagePath = fullfile(absPath, imageFile); % absolute path to the image file
            app.Image.ImageSource = imagePath;
            app.UIFigure.Name = 'ROAST: A simulator for transcranial electrical stimulation';

            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @CustomUpdateAppLayout, true);

            app.SimulationtagEditField.Value = "Date and time e.g. 20221225T120100";

            %CreateElecTab(app, 1);
            FillElecTab(app, 1);
            CreateElecTab(app, 2);
            FillElecTab(app, 2);

            app.PreviousElecType{1} = 'Disc (default)';
            app.PreviousElecType{2} = 'Disc (default)';

        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [T1file,T1path] = uigetfile('*.nii');  % Open a T1 MRI file
            app.T1FileLoc.Value = strcat(T1path,T1file);
        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            [T2file,T2path] = uigetfile('*.nii');  % Open a T2 MRI file
            app.T2FileLoc.Value = strcat(T2path,T2file);
        end

        % Value changed function: NumberofelectrodesEditField
        function NumberofelectrodesEditFieldValueChanged(app, event)
            RequiredNumTabs = app.NumberofelectrodesEditField.Value;
            CurrentNumTabs = size(app.DynamicElecTab,2) + 1;

            if (mod(RequiredNumTabs, 1) ~= 0) && (RequiredNumTabs < 2)
                uif = uifigure();
                uialert(uif,'Please enter a whole number value of 2 or more in "Number of electrodes"','Warning','Icon','warning');
            elseif (RequiredNumTabs < 2)
                uif = uifigure();
                uialert(uif,'Please enter a value of 2 or more in "Number of electrodes"','Warning','Icon','warning');
            elseif mod(RequiredNumTabs, 1) ~= 0
                uif = uifigure();
                uialert(uif,'Please enter a whole number in "Number of electrodes"','Warning','Icon','warning');
            elseif RequiredNumTabs > CurrentNumTabs % Tab creation
                NumNewTabs = RequiredNumTabs - CurrentNumTabs;
                for i = 1:NumNewTabs
                    fprintf("Tab to create: %d \n", (CurrentNumTabs + i))
                    CreateElecTab(app, (CurrentNumTabs + i));
                    FillElecTab(app, (CurrentNumTabs + i));
                end
            elseif RequiredNumTabs < CurrentNumTabs % Tab deletion
                NumDeleteTabs = CurrentNumTabs - RequiredNumTabs;
                for i = 1:NumDeleteTabs
                    DeleteElecTab(app, (CurrentNumTabs - i));
                end
            end
        end

        % Callback function
        function TypeDropDownValueChanged(app, event)
            TabNum = str2double(app.ElectrodeTabGroup.SelectedTab.Title);
            if TabNum == 1
                currentTabObj = app.ElecTab;
            else
                currentTabObj = app.DynamicElecTab{TabNum-1};
            end

            % Create pad orientation dropdown for the first tab
            value = app.ElecTypeDropDown{TabNum}.Value;
            if ~strcmp(value, app.PreviousElecType{TabNum}) % Type has changed
                if strcmp(app.PreviousElecType{TabNum}, 'Pad')
                    % No longer pad so length, width and orientaiton
                    % dropdown deleted
                    delete(app.OrientationDropDown);
                    delete(app.OrientationText);

                    delete(app.LengthLabel);
                    delete(app.LengthValue);

                    delete(app.WidthLabel);
                    delete(app.WidthValue);

                elseif strcmp(app.PreviousElecType{TabNum}, 'Disc (default)')
                    % No longer disc so radius deleted
                    delete(app.RadiusValue{TabNum});
                    delete(app.RadiusLabel{TabNum});
                elseif strcmp(app.PreviousElecType{TabNum}, 'Ring')
                    delete(app.InnerRadiusLabel);
                    delete(app.InnerRadiusValue);
                    delete(app.OuterRadiusLabel);
                    delete(app.OuterRadiusValue);
                end
            end
            app.PreviousElecType{TabNum} = value;
            % Create dropdowm menu for electrode orientation and
            % include x and y sizing
            if strcmp(value, 'Pad')
                app.OrientationDropDown = uidropdown(currentTabObj);
                app.OrientationDropDown.Items = {'Long axis going left/right', 'Long axis going front/back', 'Long axis going up/down'};
                app.OrientationDropDown.Position = [95 10 175 22];
                app.OrientationDropDown.Value = 'Long axis going left/right';

                app.OrientationText = uilabel(currentTabObj);
                app.OrientationText.HorizontalAlignment = 'left';
                app.OrientationText.Position = [6 10 90 22];
                app.OrientationText.Text = 'Orientation';

                app.HeightLabel{TabNum}.Position = [6, 45, 74, 22];
                app.HeightValue{TabNum}.Position = [117, 45, 34, 22];

                app.LengthLabel = uilabel(currentTabObj);
                app.LengthLabel.HorizontalAlignment = 'left';
                app.LengthLabel.Position = [6 80 74 22];
                app.LengthLabel.Text = 'Length (mm)';

                app.LengthValue = uieditfield(currentTabObj, 'numeric');
                app.LengthValue.Position = [117 80 34 22];

                app.WidthLabel = uilabel(currentTabObj);
                app.WidthLabel.HorizontalAlignment = 'left';
                app.WidthLabel.Position = [190 80 71 22];
                app.WidthLabel.Text = 'Width (mm)';

                app.WidthValue = uieditfield(currentTabObj, 'numeric');
                app.WidthValue.Position = [260 80 34 22];

            elseif strcmp(value, 'Disc (default)')
                app.HeightLabel{TabNum}.Position = [190 80 71 22];
                app.HeightValue{TabNum}.Position = [260 80 34 22];

                % Create RadiusLabel{TabNum}
                app.RadiusLabel{TabNum} = uilabel(currentTabObj);
                app.RadiusLabel{TabNum}.Position = [7 80 74 22];
                app.RadiusLabel{TabNum}.Text = 'Radius (mm)';

                % Create RadiusValue{TabNum}
                app.RadiusValue{TabNum} = uieditfield(currentTabObj, 'numeric');
                app.RadiusValue{TabNum}.Position = [117 80 34 22];
                
            elseif strcmp(value, 'Ring')
                
                app.InnerRadiusLabel = uilabel(currentTabObj);
                app.InnerRadiusLabel.HorizontalAlignment = 'left';
                app.InnerRadiusLabel.Position = [6 80 100 22];
                app.InnerRadiusLabel.Text = 'Inner radius (mm)';

                app.InnerRadiusValue = uieditfield(currentTabObj, 'numeric');
                app.InnerRadiusValue.Position = [117 80 34 22];
                
                app.OuterRadiusLabel = uilabel(currentTabObj);
                app.OuterRadiusLabel.HorizontalAlignment = 'left';
                app.OuterRadiusLabel.Position = [6 45 100 22];
                app.OuterRadiusLabel.Text = 'Outer radius (mm)';

                app.OuterRadiusValue = uieditfield(currentTabObj, 'numeric');
                app.OuterRadiusValue.Position = [117 45 34 22];
                
                app.HeightLabel{TabNum}.Position = [190, 80, 74, 22];
                app.HeightValue{TabNum}.Position = [260, 80, 34, 22];
            end
        end

        % Callback function
        function PositionEditFieldValueChanged(app, event)
            value = app.PositionEditField.Value;
            ElectrodePos = ["nz" "lpa" "rpa" "Fp1", "Fpz", "Fp2", "F7", "F3", "Fz", "F4", "F8", ...
                "T7", "C3", "Cz", "C4", "T8",  "P7", "P3", "Pz", "P4", "P8", "O1", "Oz", "O2"];
            if strcmp(app.CaptypeDropDown.Value, '1020')
                if ~ismember(value, ElectrodePos)
                    uif = uifigure();
                    uialert(uif,'The electrode position you entered was not valid. Please refer to the electrode position image.','Warning','Icon','warning');
                end
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

            % Check T1 MRI is included, if not alert user and do not
            % continue
            T1Location = app.T1FileLoc.Value;
            if isempty(T1Location)
                uif = uifigure();
                uialert(uif,'Please select a T1 MRI file before running ROAST.','Warning','Icon','warning');
                return;
            end

            T2Location = app.T2FileLoc.Value;

            Electrode1Intensity = app.StimulationintensitymAEditField.Value;
            Electrode2Intensity = app.StimulationintensitymAEditField_2.Value;

            if (Electrode1Intensity + Electrode2Intensity) ~= 0
                uif = uifigure();
                uialert(uif,'Please ensure the sum of electrode intensities are equal to 0.','Warning','Icon','warning');
                %PrintToScreen(app, "Please ensure the sum of electrode intensities are equal to 0");
                %PrintToScreen(app, "------ ROAST WAS NOT ABLE TO START ------");
                return;
            end
            Electrode1Position = app.PositionEditField.Value;

            Electrode1Type = app.TypeDropDown.Value;
            text = strcat("(Electrode 1) Position = ", string(Electrode1Position), " Intensity = ", string(Electrode1Intensity), " Type = ", string(Electrode1Type));
            if strcmp(Electrode1Type, "Pad")
                Electrode1Orientation = app.DynamicOrientationDropDown{1}.Value;
                text = strcat(text, " Orientation = ", string(Electrode1Orientation));
            end
            %PrintToScreen(app, text);

            Electrode2Position = app.PositionEditField_2.Value;

            Electrode2Type = app.TypeDropDown_2.Value;
            text = strcat("(Electrode 2) Position = ", string(Electrode2Position), " Intensity = ", string(Electrode2Intensity), " Type = ", string(Electrode2Type));
            if strcmp(Electrode2Type, "Pad")
                Electrode2Orientation = app.DynamicOrientationDropDown{2}.Value;
                text = strcat(text, " Orientation = ", string(Electrode1Orientation));
            end
            %PrintToScreen(app, text);

            %% Check if any advanced settings have been changed
            advancedRoastInput = [];
            if app.ResamplingCheckBox.Value
                advancedRoastInput = [advancedRoastInput, 'resampling', 'on'];
                %PrintToScreen(app, "Advanced setting: Resampling ON");
            end

            if app.ZeropaddingEditField.Value ~= 0
                advancedRoastInput = [advancedRoastInput, 'zeropadding', app.ZeropaddingEditField.Value];
                %PrintToScreen(app, strcat("Advanced setting: Zero padding = ", string(app.ZeropaddingEditField.Value)));
            end

            if ~strcmp(app.SimulationtagEditField.Value, "Date and time e.g. 20221225T120100")
                advancedRoastInput = [advancedRoastInput, 'simulationTag', string(pap.SimulationtagEditField.Value)];
            end
            % Check each conductivity value and save to struct if they have
            % been changed from default
            NewConductivies = struct();
            if app.WhitematterEditField.Value ~= 0.126
                %PrintToScreen(app, strcat("Advanced setting: White matter conductivity has been changed to ", string(app.WhitematterEditField.Value), " S/m"));
                NewConductivies.white = app.WhitematterEditField.Value;
            end
            if app.GraymatterEditField.Value ~= 0.276
                %PrintToScreen(app, strcat("Advanced setting: Grey matter conductivity has been changed to ", string(app.GraymatterEditField.Value), " S/m"));
                NewConductivies.gray = app.GraymatterEditField.Value;
            end
            if app.CerebrospinalfluidEditField.Value ~= 1.65
                %PrintToScreen(app, strcat("Advanced setting: Cerebrospinal fluid conductivity has been changed to ", string(app.CerebrospinalfluidEditField.Value), " S/m"));
                NewConductivies.csf = app.CerebrospinalfluidEditField.Value;
            end
            if app.BoneEditField.Value ~= 0.01
                %PrintToScreen(app, strcat("Advanced setting: Bone conductivity has been changed to ", string(app.BoneEditField.Value), " S/m"));
                NewConductivies.bone = app.BoneEditField.Value;
            end
            if app.SkinEditField.Value ~= 0.465
                %PrintToScreen(app, strcat("Advanced setting: Skin conductivity has been changed to ", string(app.SkinEditField.Value), " S/m"));
                NewConductivies.skin = app.SkinEditField.Value;
            end
            if app.AirEditField.Value ~= 2.5e-14
                %PrintToScreen(app, strcat("Advanced setting: Air conductivity has been changed to ", string(app.AirEditField.Value), " S/m"));
                NewConductivies.air = app.AirEditField.Value;
            end
            if app.GelEditField.Value ~= 0.3
                %PrintToScreen(app, strcat("Advanced setting: Gel conductivity has been changed to ", string(app.GelEditField.Value), " S/m"));
                NewConductivies.gel = app.GelEditField.Value;
            end
            if app.ElectrodeEditField.Value ~= 5.9e7
                %PrintToScreen(app, strcat("Advanced setting: Electrode conductivity has been changed to ", string(app.ElectrodeEditField.Value), " S/m"));
                NewConductivies.electrode = app.ElectrodeEditField.Value;
            end

            % Check each mesh option and save to struct if they have
            % been changed from default
            NewMeshOptions = struct();
            if app.MaximalsurfaceelementsizeEditField.Value ~= 5
                %PrintToScreen(app, strcat("Advanced setting: Maximal surface element size has been changed to ", string(app.MaximalsurfaceelementsizeEditField.Value), " S/m"));
                NewMeshOptions.radbound = app.MaximalsurfaceelementsizeEditField.Value;
            end
            if app.MinimalangleofasurfacetriangleEditField.Value ~= 30
                %PrintToScreen(app, strcat("Advanced setting: Minimum angle of a surface triangle has been changed to ", string(app.MinimalangleofasurfacetriangleEditField.Value), "S /m"));
                NewMeshOptions.angbound = app.MinimalangleofasurfacetriangleEditField.Value;
            end
            if app.MaximumdistancebetweenEditField.Value ~= 0.3
                %PrintToScreen(app, strcat("Advanced setting: Maximal distance surface bounding circle and element bounding sphere centers has been changed to ", string(app.MaximumdistancebetweenEditField.Value), " S/m"));
                NewMeshOptions.distbound = app.MaximumdistancebetweenEditField.Value;
            end
            if app.MaximalradiusedgeratioEditField.Value ~= 3
                %PrintToScreen(app, strcat("Advanced setting: Maximal radius-edge ratio has been changed to ", string(app.MaximalradiusedgeratioEditField.Value), " S/m"));
                NewMeshOptions.reratio = app.MaximalradiusedgeratioEditField.Value;
            end
            if app.MaximaltetrahedralelementvolumeEditField.Value ~= 10
                %PrintToScreen(app, strcat("Advanced setting: Maximal tetrahedral element volume has been changed to ", string(app.MaximaltetrahedralelementvolumeEditField.Value), " S/m"));
                NewMeshOptions.maxvol = app.MaximaltetrahedralelementvolumeEditField.Value;
            end


            %roast(T1Location, {string(Electrode1Position), Electrode1Intensity, string(Electrode2Position), Electrode2Intensity})
        end

        % Callback function
        function ImageClicked(app, event)
            
            FigurePosition = app.UIFigure.Position; % Current position and size of app window
            CurrentTab = str2double(app.ElectrodeTabGroup.SelectedTab.Title);
            % Does cell corresponding to electrode exist already?
            if size(app.ElecImg, 2) >= CurrentTab
                if size(app.ElecImg{CurrentTab}, 1) == 1 % Image already exists
                    delete(app.ElecImg{CurrentTab})
                    app.ElecImg{CurrentTab} = []; % Delete image
                    delete(app.ElecImgTxt{CurrentTab})
                    app.ElecImgTxt{CurrentTab} = []; % Delete text
                end
            end
            % As the width of the window changes the position of the click
            % changes. This calculates the difference so regardless of
            % window size the click position always aligns with electrode
            % in image
            ClickPos = app.UIFigure.CurrentPoint; % Position of click
            PosToUpdate = 'NoUpdate'; % Default to not updating electrode position

            %% Track click location and save new electrode position in  PosToUpdate
            tabRatio = app.TabGroup3.Position(3) / app.TabGroup3.Position(4);

            if tabRatio > app.IMG_RATIO
                % Spaces to the side of the image
                xShift = round((app.TabGroup3.Position(3)-(app.TabGroup3.Position(4)*(1/app.IMG_RATIO)))/2);
                YClickPerc = ClickPos(2) / (app.Image.Position(4) / 100);
                XClickPerc = (ClickPos(1) - xShift - app.LEFT_COLLUMN_WIDTH) / (((app.TabGroup3.Position(4)*(1/app.IMG_RATIO))) / 100);
            else
                % Spaces above and below the image
                yShift = round((app.TabGroup3.Position(4)-(app.TabGroup3.Position(3)*(app.IMG_RATIO)))/2) - 15;
                YClickPerc = (ClickPos(2)-yShift) / ((app.TabGroup3.Position(3)*app.IMG_RATIO)/ 100);
                %YClickPerc = round((app.TabGroup3.Position(4)-(app.TabGroup3.Position(3)*IMG_RATIO))/2);
                XClickPerc = (ClickPos(1)-app.LEFT_COLLUMN_WIDTH) / (app.Image.Position(3) / 100);
            end

            if (YClickPerc > 46) && (YClickPerc < 56) % Middle row Y range
                if(XClickPerc > 9) && (XClickPerc < 19)
                    PosToUpdate = 'T3';
                elseif(XClickPerc > 28) && (XClickPerc < 38)
                    PosToUpdate = 'C3';
                elseif(XClickPerc > 46) && (XClickPerc < 56)
                    PosToUpdate = 'Cz';
                elseif(XClickPerc > 64) && (XClickPerc < 74)
                    PosToUpdate = 'C4';
                elseif(XClickPerc > 83) && (XClickPerc < 93)
                    PosToUpdate = 'T4';
                end
            end
            if (YClickPerc > 65) && (YClickPerc < 75) % F3, Fz, F4
                if (XClickPerc > 31) && (XClickPerc < 41)
                    PosToUpdate = 'F3';
                elseif(XClickPerc > 46) && (XClickPerc < 56)
                    PosToUpdate = 'Fz';
                elseif(XClickPerc > 61) && (XClickPerc < 71)
                    PosToUpdate = 'F4';
                end
            end
            if (YClickPerc > 27) && (YClickPerc < 38) % P3, Pz, P4
                if (XClickPerc > 30) && (XClickPerc < 41)
                    PosToUpdate = 'P3';
                elseif(XClickPerc > 46) && (XClickPerc < 56)
                    PosToUpdate = 'Pz';
                elseif(XClickPerc > 61) && (XClickPerc < 71)
                    PosToUpdate = 'P4';
                end
            end
            if (YClickPerc > 10) && (YClickPerc < 21) % O1, O2
                if (XClickPerc > 34) && (XClickPerc < 45)
                    PosToUpdate = 'O1';
                elseif(XClickPerc > 58) && (XClickPerc < 69)
                    PosToUpdate = 'O2';
                end
            end
            if (YClickPerc > 81) && (YClickPerc < 92) % Fp1, Fp2
                if (XClickPerc > 34) && (XClickPerc < 45)
                    PosToUpdate = 'Fp1';
                elseif(XClickPerc > 58) && (XClickPerc < 69)
                    PosToUpdate = 'Fp2';
                end
            end
            if (YClickPerc > 24) && (YClickPerc < 35) % T5, T6
                if (XClickPerc > 16) && (XClickPerc < 27)
                    PosToUpdate = 'T5';
                elseif(XClickPerc > 75) && (XClickPerc < 86)
                    PosToUpdate = 'T6';
                end
            end
            if (YClickPerc > 68) && (YClickPerc < 77) % F7, F8
                if (XClickPerc > 16) && (XClickPerc < 27)
                    PosToUpdate = 'F7';
                elseif(XClickPerc > 75) && (XClickPerc < 85)
                    PosToUpdate = 'F8';
                end
            end

            %% Update electrode position in relevant electrode tab if needed
            if ~strcmp(PosToUpdate, 'NoUpdate')
                CurrentTab = str2double(app.ElectrodeTabGroup.SelectedTab.Title);
                HighlightElectrodePos(app, PosToUpdate);
                app.ElecPosValue{CurrentTab}.Value = PosToUpdate;
            end

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {512, 512};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {368, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup2 = uitabgroup(app.LeftPanel);
            app.MainmenuTab = uitab(app.TabGroup2);
            app.MainmenuTab.Title = 'Main menu';
            app.CaptypeDropDownLabel = uilabel(app.MainmenuTab);
            app.CaptypeDropDownLabel.Text = 'Cap type';
            app.CaptypeDropDown = uidropdown(app.MainmenuTab);
            app.CaptypeDropDown.Items = {'1010 (default)', '1020', '1005', 'BioSemi', 'EGI'};
            app.CaptypeDropDown.Value = '1010 (default)';
            app.NumberofelectrodesEditFieldLabel = uilabel(app.MainmenuTab);
            app.NumberofelectrodesEditFieldLabel.Text = 'Number of electrodes';
            app.NumberofelectrodesEditField = uieditfield(app.MainmenuTab, 'numeric');
            app.NumberofelectrodesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofelectrodesEditFieldValueChanged, true);
            app.NumberofelectrodesEditField.Value = 2;
            app.ElectrodeconfigurationLabel = uilabel(app.MainmenuTab);
            app.ElectrodeconfigurationLabel.Text = 'Electrode configuration';
            app.ElectrodeTabGroup = uitabgroup(app.MainmenuTab);
            app.ElecTab = uitab(app.ElectrodeTabGroup);
            app.ElecTab.Title = '1';
            app.StartButton = uibutton(app.MainmenuTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.MainmenuTab, 'push');
            app.StopButton.Text = 'Stop';
            app.BrowseButton = uibutton(app.MainmenuTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.T1FileLoc = uieditfield(app.MainmenuTab, 'text');
            app.BrowseButton_2 = uibutton(app.MainmenuTab, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Text = 'Browse';
            app.T2FileLoc = uieditfield(app.MainmenuTab, 'text');
            app.SelectT1MRIfileLabel = uilabel(app.MainmenuTab);
            app.SelectT1MRIfileLabel.Text = 'Select T1 MRI file';
            app.OptionalSelectT2MRIfileLabel = uilabel(app.MainmenuTab);
            app.OptionalSelectT2MRIfileLabel.Text = '(Optional) Select T2 MRI file';
            app.SavesettingsButton = uibutton(app.MainmenuTab, 'push');
            app.SavesettingsButton.Text = 'Save settings';
            app.LoadsettingsButton = uibutton(app.MainmenuTab, 'push');
            app.LoadsettingsButton.Text = 'Load settings';
            app.TargetedtESTab = uitab(app.TabGroup2);
            app.TargetedtESTab.Title = 'Targeted tES';
            app.StartButton_2 = uibutton(app.TargetedtESTab, 'push');
            app.StartButton_2.Text = 'Start';
            app.StopButton_2 = uibutton(app.TargetedtESTab, 'push');
            app.StopButton_2.Text = 'Stop';
            app.BrowseButton_3 = uibutton(app.TargetedtESTab, 'push');
            app.BrowseButton_3.Text = 'Browse';
            app.T1FileLoc_2 = uieditfield(app.TargetedtESTab, 'text');
            app.BrowseButton_4 = uibutton(app.TargetedtESTab, 'push');
            app.BrowseButton_4.Text = 'Browse';
            app.T2FileLoc_2 = uieditfield(app.TargetedtESTab, 'text');
            app.SelectT1MRIfileLabel_2 = uilabel(app.TargetedtESTab);
            app.SelectT1MRIfileLabel_2.Text = 'Select T1 MRI file';
            app.OptionalSelectT2MRIfileLabel_2 = uilabel(app.TargetedtESTab);
            app.OptionalSelectT2MRIfileLabel_2.Text = '(Optional) Select T2 MRI file';
            app.SavesettingsButton_2 = uibutton(app.TargetedtESTab, 'push');
            app.SavesettingsButton_2.Text = 'Save settings';
            app.LoadsettingsButton_2 = uibutton(app.TargetedtESTab, 'push');
            app.LoadsettingsButton_2.Text = 'Load settings';
            app.CapType1010TypeDiscRadius6mmHeight2mmLabel = uilabel(app.TargetedtESTab);
            app.CapType1010TypeDiscRadius6mmHeight2mmLabel.Text = 'Cap Type (1010)  Type (Disc)  Radius (6mm)  Height (2mm)';
            app.CoordinatetypeDropDownLabel = uilabel(app.TargetedtESTab);
            app.CoordinatetypeDropDownLabel.Text = 'Coordinate type';
            app.CoordinatetypeDropDown = uidropdown(app.TargetedtESTab);
            app.CoordinatetypeDropDown.Items = {'MNI', 'Voxel'};
            app.CoordinatetypeDropDown.Value = 'Voxel';
            app.PriorityDropDownLabel = uilabel(app.TargetedtESTab);
            app.PriorityDropDownLabel.Text = 'Priority';
            app.PriorityDropDown = uidropdown(app.TargetedtESTab);
            app.PriorityDropDown.Items = {'Max Intensity', 'Max Focality'};
            app.PriorityDropDown.Value = 'Max Intensity';
            app.AlgorithmDropDownLabel = uilabel(app.TargetedtESTab);
            app.AlgorithmDropDownLabel.Text = 'Algorithm';
            app.AlgorithmDropDown = uidropdown(app.TargetedtESTab);
            app.AlgorithmDropDown.Items = {'Max L1', 'Max L1per'};
            app.AlgorithmDropDown.Value = 'Max L1per';
            app.ElectricfieldorientationDropDownLabel = uilabel(app.TargetedtESTab);
            app.ElectricfieldorientationDropDownLabel.Text = 'Electric field orientation';
            app.ElectricfieldorientationDropDown = uidropdown(app.TargetedtESTab);
            app.ElectricfieldorientationDropDown.Items = {'Radial in', 'Radial out', 'Right', 'Left', 'Anterior', 'Posterior', 'Right anterior', 'Right posterior', 'Left anterior', 'Left posterior', 'Optimal'};
            app.ElectricfieldorientationDropDown.Value = 'Right posterior';
            app.AdvancedTab = uitab(app.TabGroup2);
            app.AdvancedTab.Title = 'Advanced';
            app.SimulationtagEditFieldLabel = uilabel(app.AdvancedTab);
            app.SimulationtagEditFieldLabel.Text = 'Simulation tag';
            app.SimulationtagEditField = uieditfield(app.AdvancedTab, 'text');
            app.ResamplingCheckBox = uicheckbox(app.AdvancedTab);
            app.ResamplingCheckBox.Text = 'Resampling';
            app.ZeropaddingEditFieldLabel = uilabel(app.AdvancedTab);
            app.ZeropaddingEditFieldLabel.Text = 'Zero padding';
            app.ZeropaddingEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.TabGroup4 = uitabgroup(app.AdvancedTab);
            app.ConductivityvaluesTab = uitab(app.TabGroup4);
            app.ConductivityvaluesTab.Title = 'Conductivity values';
            app.WhitematterEditFieldLabel = uilabel(app.ConductivityvaluesTab);
            app.WhitematterEditFieldLabel.Text = 'White matter';
            app.WhitematterEditField = uieditfield(app.ConductivityvaluesTab, 'numeric');
            app.WhitematterEditField.Value = 0.126;
            app.GraymatterEditFieldLabel = uilabel(app.ConductivityvaluesTab);
            app.GraymatterEditFieldLabel.Text = 'Gray matter';
            app.GraymatterEditField = uieditfield(app.ConductivityvaluesTab, 'numeric');
            app.GraymatterEditField.Value = 0.276;
            app.CerebrospinalfluidLabel = uilabel(app.ConductivityvaluesTab);
            app.CerebrospinalfluidLabel.Text = 'Cerebrospinal fluid ';
            app.CerebrospinalfluidEditField = uieditfield(app.ConductivityvaluesTab, 'numeric');
            app.CerebrospinalfluidEditField.Value = 1.65;
            app.BoneEditFieldLabel = uilabel(app.ConductivityvaluesTab);
            app.BoneEditFieldLabel.Text = 'Bone';
            app.BoneEditField = uieditfield(app.ConductivityvaluesTab, 'numeric');
            app.BoneEditField.Value = 0.01;
            app.SkinEditFieldLabel = uilabel(app.ConductivityvaluesTab);
            app.SkinEditFieldLabel.Text = 'Skin';
            app.SkinEditField = uieditfield(app.ConductivityvaluesTab, 'numeric');
            app.SkinEditField.Value = 0.465;
            app.AirEditFieldLabel = uilabel(app.ConductivityvaluesTab);
            app.AirEditFieldLabel.Text = 'Air';
            app.AirEditField = uieditfield(app.ConductivityvaluesTab, 'numeric');
            app.AirEditField.Value = 2.5e-14;
            app.GelEditFieldLabel = uilabel(app.ConductivityvaluesTab);
            app.GelEditFieldLabel.Text = 'Gel';
            app.GelEditField = uieditfield(app.ConductivityvaluesTab, 'numeric');
            app.GelEditField.Value = 0.3;
            app.ElectrodeEditFieldLabel = uilabel(app.ConductivityvaluesTab);
            app.ElectrodeEditFieldLabel.Text = 'Electrode';
            app.ElectrodeEditField = uieditfield(app.ConductivityvaluesTab, 'numeric');
            app.ElectrodeEditField.Value = 59000000;
            app.SmLabel = uilabel(app.ConductivityvaluesTab);
            app.SmLabel.Text = 'S/m';
            app.SmLabel_2 = uilabel(app.ConductivityvaluesTab);
            app.SmLabel_2.Text = 'S/m';
            app.SmLabel_3 = uilabel(app.ConductivityvaluesTab);
            app.SmLabel_3.Text = 'S/m';
            app.SmLabel_4 = uilabel(app.ConductivityvaluesTab);
            app.SmLabel_4.Text = 'S/m';
            app.SmLabel_5 = uilabel(app.ConductivityvaluesTab);
            app.SmLabel_5.Text = 'S/m';
            app.SmLabel_6 = uilabel(app.ConductivityvaluesTab);
            app.SmLabel_6.Text = 'S/m';
            app.SmLabel_7 = uilabel(app.ConductivityvaluesTab);
            app.SmLabel_7.Text = 'S/m';
            app.SmLabel_8 = uilabel(app.ConductivityvaluesTab);
            app.SmLabel_8.Text = 'S/m';
            app.MeshoptionsTab = uitab(app.TabGroup4);
            app.MeshoptionsTab.Title = 'Mesh options';
            app.MaximalsurfaceelementsizeEditFieldLabel = uilabel(app.MeshoptionsTab);
            app.MaximalsurfaceelementsizeEditFieldLabel.Text = 'Maximal surface element size';
            app.MaximalsurfaceelementsizeEditField = uieditfield(app.MeshoptionsTab, 'numeric');
            app.MaximalsurfaceelementsizeEditField.Value = 5;
            app.MinimalangleofasurfacetriangleEditFieldLabel = uilabel(app.MeshoptionsTab);
            app.MinimalangleofasurfacetriangleEditFieldLabel.Text = 'Minimal angle of a surface triangle';
            app.MinimalangleofasurfacetriangleEditField = uieditfield(app.MeshoptionsTab, 'numeric');
            app.MinimalangleofasurfacetriangleEditField.Value = 30;
            app.MaximumdistancebetweenEditFieldLabel = uilabel(app.MeshoptionsTab);
            app.MaximumdistancebetweenEditFieldLabel.Text = {'Maximal distance between the '; 'center of the surface bounding circle '; 'and center of the element bounding '; 'sphere'};
            app.MaximumdistancebetweenEditField = uieditfield(app.MeshoptionsTab, 'numeric');
            app.MaximumdistancebetweenEditField.Value = 0.3;
            app.MaximalradiusedgeratioEditFieldLabel = uilabel(app.MeshoptionsTab);
            app.MaximalradiusedgeratioEditFieldLabel.Text = 'Maximal radius-edge ratio';
            app.MaximalradiusedgeratioEditField = uieditfield(app.MeshoptionsTab, 'numeric');
            app.MaximalradiusedgeratioEditField.Value = 3;
            app.MaximaltetrahedralelementvolumeEditFieldLabel = uilabel(app.MeshoptionsTab);
            app.MaximaltetrahedralelementvolumeEditFieldLabel.Text = 'Maximal tetrahedral element volume';
            app.MaximaltetrahedralelementvolumeEditField = uieditfield(app.MeshoptionsTab, 'numeric');
            app.MaximaltetrahedralelementvolumeEditField.Value = 10;
            app.InfoTab = uitab(app.TabGroup2);
            app.InfoTab.Title = 'Info';
            app.Hyperlink = uihyperlink(app.InfoTab);
            app.Hyperlink.URL = 'https://groups.google.com/g/roast-users';
            app.Hyperlink.Text = 'Roast users Google group';
            app.Hyperlink2 = uihyperlink(app.InfoTab);
            app.Hyperlink2.URL = 'https://github.com/andypotatohy/roast';
            app.Hyperlink2.Text = 'Roast github repository with examples';
            app.Hyperlink3 = uihyperlink(app.InfoTab);
            app.Hyperlink3.Text = 'GUI Roast video tutorial';
            app.Hyperlink3_2 = uihyperlink(app.InfoTab);
            app.Hyperlink3_2.Text = 'GUI Roast github repository';
            app.HelpfullinksLabel_2 = uilabel(app.InfoTab);
            app.HelpfullinksLabel_2.Text = 'Helpful links';
            app.Label = uilabel(app.InfoTab);
            app.Label.Text = {'If you use ROAST in your research, please cite the following:'; 'Huang, Y., Datta, A., Bikson, M., Parra, L.C., Realistic '; 'vOlumetric-Approach to Simulate Transcranial Electric Stimulation -- ROAST -- a '; 'fully automated open-source pipeline, Journal of Neural Engineering, Vol. 16, '; 'No. 5, 2019 (prefered reference)'; ''; 'Huang, Y., Datta, A., Bikson, M., Parra, L.C., ROAST: an open-source, fully-automated, '; 'Realistic vOlumetric-Approach-based Simulator for TES, Proceedings of the 40th '; 'Annual International Conference of the IEEE Engineering in Medicine and Biology '; 'Society, Honolulu, HI, July 2018'; ''; 'If you use New York head to run simulation, please also cite the following:'; 'Huang, Y., Parra, L.C., Haufe, S.,2016. The New York Head - A precise standardized '; 'volume conductor model for EEG source localization and tES targeting. '; 'NeuroImage,140, 150-162'; ''; 'If you also use the targeting feature (roast_target), please cite the following:'; 'Dmochowski, J.P., Datta, A., Bikson, M., Su, Y., Parra, L.C., Optimized multi-electrode '; 'stimulation increases focality and intensity at target, Journal of Neural Engineering '; '8 (4), 046011, 2011'; ''; 'Dmochowski, J.P., Datta, A., Huang, Y., Richardson, J.D., Bikson, M., Fridriksson, '; 'J., Parra, L.C., Targeted transcranial direct current stimulation for rehabilitation after '; 'stroke, NeuroImage, 75, 12-19, 2013'; ''; 'Huang, Y., Thomas, C., Datta, A., Parra, L.C., Optimized tDCS for Targeting Multiple '; 'Brain Regions: An Integrated Implementation.Proceedings of the 40th Annual '; 'International Conference of the IEEE Engineering in Medicine and Biology Society, '; 'Honolulu, HI, July 2018, 3545-3548'; ''; 'ROAST was supported by NIH through grants R01MH111896, R01MH111439, '; 'R01NS095123, R44NS092144, R41NS076123, and by Soterix Medical Inc'};
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup3 = uitabgroup(app.RightPanel);
            app.ElectrodepositionTab = uitab(app.TabGroup3);
            app.ElectrodepositionTab.Title = 'Electrode position';
            app.ClicktoselectLabel = uilabel(app.ElectrodepositionTab);
            app.ClicktoselectLabel.Text = 'Click to select';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RoastGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end